classdef Rzut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        czy                          matlab.ui.control.CheckBox
        masa                         matlab.ui.control.Spinner
        MasaobiektukgLabel           matlab.ui.control.Label
        promien                      matlab.ui.control.Slider
        PromiekuliLabel              matlab.ui.control.Label
        lepkosc                      matlab.ui.control.Slider
        WsplepkociPasLabel           matlab.ui.control.Label
        slonce                       matlab.ui.control.CheckBox
        ksiezyc                      matlab.ui.control.CheckBox
        mars                         matlab.ui.control.CheckBox
        ziemia                       matlab.ui.control.CheckBox
        g                            matlab.ui.control.Slider
        WartoscprzyciganiagrawitacyjnegoplanetyLabel  matlab.ui.control.Label
        RysujButton                  matlab.ui.control.Button
        KtstopnieSlider              matlab.ui.control.Slider
        KtstopnieSliderLabel         matlab.ui.control.Label
        WysokopocztkwoamSlider       matlab.ui.control.Slider
        WysokomLabel                 matlab.ui.control.Label
        PrdkopocztkowamsSlider       matlab.ui.control.Slider
        PrdkopocztkowamsSliderLabel  matlab.ui.control.Label
        KtGauge                      matlab.ui.control.NinetyDegreeGauge
        KtGaugeLabel                 matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        xmaxEditField                matlab.ui.control.NumericEditField
        xmaxEditFieldLabel           matlab.ui.control.Label
        hmaxEditField                matlab.ui.control.NumericEditField
        hmaxEditFieldLabel           matlab.ui.control.Label
        VmaxEditField                matlab.ui.control.NumericEditField
        VmaxEditFieldLabel           matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        Vel                          matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: KtstopnieSlider, UIAxes
        function KtSliderValueChanged(app, event)
                app.KtGauge.Value=app.KtstopnieSlider.Value;
        end

        % Value changed function: WysokopocztkwoamSlider
        function WysokopocztkwoamSliderValueChanged(app, event)
           
        end

        % Value changed function: PrdkopocztkowamsSlider
        function PrdkopocztkowamsSliderValueChanged(app, event)

        end

        % Callback function
        function ButtonPushed(app, event)
        
        end

        % Button pushed function: RysujButton
        function RysujButtonPushed(app, event)
Fg = 10^app.g.Value;
mass=app.masa.Value;
eta = 10^app.lepkosc.Value;
r = app.promien.Value;
FOP = eta*r*6*pi()/mass;
V = app.PrdkopocztkowamsSlider.Value;
kat = app.KtstopnieSlider.Value*pi/180;
h = app.WysokopocztkwoamSlider.Value;
fin=(V*cos(kat)+sqrt((V*cos(kat))^2+2*Fg*h))/(Fg);
thm=V*cos(kat)/(Fg);
hm=h+thm*V*cos(kat)-Fg*thm^2/2;
xm=fin*V*sin(kat);
vx=V*sin(kat);
vy=V*cos(kat);
nor2=vx^2;
finx=abs(fin*21)+1;
tma=vy/Fg;
hma=abs(h+tma*vy-Fg*tma^2/(2));
vma=sqrt(nor2+(vy-fin*Fg)^2);
app.VmaxEditField.Value=vma;
app.hmaxEditField.Value=hm;
app.xmaxEditField.Value=xm;

hold(app.UIAxes,'off') ;
app.UIAxes.XLim=[ 0 xm+1];
app.UIAxes.YLim=[0 hm+1];
app.UIAxes_2.XLim=[ 0 finx];
app.UIAxes_2.YLim=[ 0 hma+1];
app.Vel.XLim=[ 0 finx];
app.Vel.YLim=[ 0 vma+1];
if app.czy.Value == 1
    FOP=0;
end

for i=0:0.01:fin
    if 1-FOP*i>=0.1
        vxx=vx-FOP*i*vx;
        vyy=vy-FOP*i*vx;    
    end
    x=vxx*i;
    t=0:0.05:i;   
    v=sqrt(vxx^2+(vyy-t.*Fg).^2);
    p=h+i*vyy-Fg*i^2/2;
    y=h+t.*vyy-Fg*t.^2/(2);
    if min(y)<0
        break;
    end
    plot(app.Vel,v,'b');
    plot(app.UIAxes_2,y,'r'); 
    plot(app.UIAxes,x,p,'o');
    pause(0.001);
end
        end

        % Value changed function: VmaxEditField
        function VmaxEditFieldValueChanged(app, event)

        end

        % Value changed function: hmaxEditField
        function hmaxEditFieldValueChanged(app, event)
            
        end

        % Value changed function: xmaxEditField
        function xmaxEditFieldValueChanged(app, event)
            
        end

        % Value changed function: slonce
        function slonceValueChanged(app, event)
            app.g.Value = log10(275);
            app.lepkosc.Value = log10(4.1*10^-5);
            app.mars.Value=0;
            app.ziemia.Value=0;
            app.ksiezyc.Value=0;
        end

        % Value changed function: ksiezyc
        function ksiezycValueChanged(app, event)
            app.g.Value = log10(1.6);
            app.mars.Value=0;
            app.ziemia.Value=0;
            app.slonce.Value=0;
        end

        % Value changed function: mars
        function marsValueChanged(app, event)
            app.g.Value = log10(3.7);
            app.lepkosc.Value = log10(1.3*10^-5);
            app.ksiezyc.Value=0;
            app.ziemia.Value=0;
            app.slonce.Value=0;
        end

        % Value changed function: ziemia
        function ziemiaValueChanged(app, event)
            app.g.Value = log10(9.81);
            app.lepkosc.Value=-5;
            app.mars.Value=0;
            app.ksiezyc.Value=0;
            app.slonce.Value=0;
        end

        % Value changed function: g
        function gValueChanged(app, event)
            app.ksiezyc.Value=0;
            app.ziemia.Value=0;
            app.mars.Value=0;
            app.slonce.Value=0;
        end

        % Value changed function: lepkosc
        function lepkoscValueChanged(app, event)
            app.ziemia.Value=0;
            app.mars.Value=0;
            app.slonce.Value=0;
            app.ksiezyc.Value=0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {772, 772};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {298, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.KtGaugeLabel = uilabel(app.LeftPanel);
            app.KtGaugeLabel.Text = {'Kąt'; ''};
            app.KtGauge = uigauge(app.LeftPanel, 'ninetydegree');
            app.KtGauge.Limits = [0 90];
            app.PrdkopocztkowamsSliderLabel = uilabel(app.LeftPanel);
            app.PrdkopocztkowamsSliderLabel.Text = 'Prędkość początkowa [m/s]';
            app.PrdkopocztkowamsSlider = uislider(app.LeftPanel);
            app.PrdkopocztkowamsSlider.ValueChangedFcn = createCallbackFcn(app, @PrdkopocztkowamsSliderValueChanged, true);
            app.WysokomLabel = uilabel(app.LeftPanel);
            app.WysokomLabel.Text = 'Wysokość początkwoa [m]';
            app.WysokopocztkwoamSlider = uislider(app.LeftPanel);
            app.WysokopocztkwoamSlider.ValueChangedFcn = createCallbackFcn(app, @WysokopocztkwoamSliderValueChanged, true);
            app.KtstopnieSliderLabel = uilabel(app.LeftPanel);
            app.KtstopnieSliderLabel.Text = 'Kąt [stopnie]';
            app.KtstopnieSlider = uislider(app.LeftPanel);
            app.KtstopnieSlider.Limits = [0 90];
            app.KtstopnieSlider.ValueChangedFcn = createCallbackFcn(app, @KtSliderValueChanged, true);
            app.RysujButton = uibutton(app.LeftPanel, 'push');
            app.RysujButton.ButtonPushedFcn = createCallbackFcn(app, @RysujButtonPushed, true);
            app.RysujButton.Text = {'Rysuj'; ''};
            app.WartoscprzyciganiagrawitacyjnegoplanetyLabel = uilabel(app.LeftPanel);
            app.WartoscprzyciganiagrawitacyjnegoplanetyLabel.Text = 'Wartosc przyciągania grawitacyjnego planety [m/s^2]';
            app.g = uislider(app.LeftPanel);
            app.g.Limits = [-1 3];
            app.g.MajorTicks = [-1 0 1 2 3];
            app.g.MajorTickLabels = {'0.1', '1', '10', '100', '1000'};
            app.g.ValueChangedFcn = createCallbackFcn(app, @gValueChanged, true);
            app.g.MinorTicks = [];
            app.g.Value = 3;
            app.ziemia = uicheckbox(app.LeftPanel);
            app.ziemia.ValueChangedFcn = createCallbackFcn(app, @ziemiaValueChanged, true);
            app.ziemia.Text = 'Ciało jest na Ziemi';
            app.mars = uicheckbox(app.LeftPanel);
            app.mars.ValueChangedFcn = createCallbackFcn(app, @marsValueChanged, true);
            app.mars.Text = 'Ciało jest na Marsie';
            app.ksiezyc = uicheckbox(app.LeftPanel);
            app.ksiezyc.ValueChangedFcn = createCallbackFcn(app, @ksiezycValueChanged, true);
            app.ksiezyc.Text = 'Ciało jest na Księżycu';
            app.slonce = uicheckbox(app.LeftPanel);
            app.slonce.ValueChangedFcn = createCallbackFcn(app, @slonceValueChanged, true);
            app.slonce.Text = 'Ciało jest na Słońcu';
            app.WsplepkociPasLabel = uilabel(app.LeftPanel);
            app.WsplepkociPasLabel.Text = 'Wsp lepkości [Pa*s]';
            app.lepkosc = uislider(app.LeftPanel);
            app.lepkosc.Limits = [-5 1];
            app.lepkosc.MajorTicks = [-5 -2 1];
            app.lepkosc.MajorTickLabels = {'10^-5', '10^-2', '10'};
            app.lepkosc.ValueChangedFcn = createCallbackFcn(app, @lepkoscValueChanged, true);
            app.lepkosc.MinorTicks = [];
            app.lepkosc.Value = -5;
            app.PromiekuliLabel = uilabel(app.LeftPanel);
            app.PromiekuliLabel.Text = 'Promień kuli [m]';
            app.promien = uislider(app.LeftPanel);
            app.promien.Limits = [0 10];
            app.promien.MajorTicks = [0 2 4 6 8 10];
            app.MasaobiektukgLabel = uilabel(app.LeftPanel);
            app.MasaobiektukgLabel.Text = 'Masa obiektu [kg]';
            app.masa = uispinner(app.LeftPanel);
            app.masa.Limits = [0.01 Inf];
            app.masa.Value = 10;
            app.czy = uicheckbox(app.LeftPanel);
            app.czy.Text = 'Czy pomijać opór powietrza? (wybór zalecany)';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Rzut')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'Y(x)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.LineStyleOrder = {'-'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @KtSliderValueChanged, true);
            app.Vel = uiaxes(app.RightPanel);
            title(app.Vel, 'Prędkość')
            xlabel(app.Vel, 't')
            ylabel(app.Vel, 'V(t)')
            zlabel(app.Vel, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'wysokość')
            xlabel(app.UIAxes_2, {'t'; ''})
            ylabel(app.UIAxes_2, 'h(t)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.LineStyleOrder = {'-'};
            app.VmaxEditFieldLabel = uilabel(app.RightPanel);
            app.VmaxEditFieldLabel.Text = {'Vmax'; ''};
            app.VmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.VmaxEditField.ValueChangedFcn = createCallbackFcn(app, @VmaxEditFieldValueChanged, true);
            app.hmaxEditFieldLabel = uilabel(app.RightPanel);
            app.hmaxEditFieldLabel.Text = {'hmax'; ''};
            app.hmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.hmaxEditField.ValueChangedFcn = createCallbackFcn(app, @hmaxEditFieldValueChanged, true);
            app.xmaxEditFieldLabel = uilabel(app.RightPanel);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmaxEditField = uieditfield(app.RightPanel, 'numeric');
            app.xmaxEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rzut

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end